classdef MA1_S2119032_Lai_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        pathTextArea                  matlab.ui.control.TextArea
        BrowerButton                  matlab.ui.control.Button
        StartspeedKnobLabel           matlab.ui.control.Label
        StartspeedKnob                matlab.ui.control.DiscreteKnob
        EndspeedKnobLabel             matlab.ui.control.Label
        EndspeedKnob                  matlab.ui.control.DiscreteKnob
        AudiospeedSwitchLabel         matlab.ui.control.Label
        AudiospeedSwitch              matlab.ui.control.Switch
        PleaseputyouraudiofileandAppinthesamefolderLabel_2  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        StopButton                    matlab.ui.control.Button
        ComputeButton                 matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        PlayButton                    matlab.ui.control.Button
        OverlapPercentageSliderLabel  matlab.ui.control.Label
        OverlapPercentageSlider       matlab.ui.control.Slider
        FramesizemsSliderLabel        matlab.ui.control.Label
        FramesizemsSlider             matlab.ui.control.Slider
        SaveButton                    matlab.ui.control.Button
        PhasevocoderLabel             matlab.ui.control.Label
        Pitchshift12EditFieldLabel    matlab.ui.control.Label
        Pitchshift12EditField         matlab.ui.control.NumericEditField
    end

    %1.choose audio file in the browser
    %2.ajust your paramenter
    %3.compute
    %4.play the result
    %5.save your result as 'output.wav' in the same folder
    
    properties (Access = private)
        start1 % Description
        stop1 % Description
        y % Description
        Fs % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowerButton
        function BrowerButtonPushed(app, event)
            [audio,path]=uigetfile('*.wav');
            audio_path=[path,audio];
            if (audio_path ~= "")
                app.pathTextArea.Value=audio_path; 
                set(app.Lamp,'color',[0,1,0])
            end    
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            function radian = ppa(phi)
            radian = phi-round(phi/(2*pi))*2*pi;
            end
            [x,Fs]=audioread(char(app.pathTextArea.Value));
            %transfer to stereo audio
            if size(x,2)==2
                x=0.5*x(:,1)+0.5*x(:,2);
            end
            x = 0.5*sum(x,2);
            %cent
            c       = app.Pitchshift12EditField.Value;
            
            %pitch shift ratio
            ps      = 2^(c/12);
            
            switch app.StartspeedKnob.Value
                case '0.5X'
                app.start1 = 2;
                case '0.75X'
                app.start1 = 1.25;
                case '1X'
                app.start1 = 1;
                case '1.25X'
                app.start1 = 0.8;
                case '2X'
                app.start1 = 0.5;
                case '4X'
                app.start1 = 0.25;
            end
                        
            switch app.EndspeedKnob.Value
                case '0.5X'
                app.stop1 = 2;
                case '0.75X'
                app.stop1 = 1.25;
                case '1X'
                app.stop1 = 1;
                case '1.25X'
                app.start1 = 0.8;
                case '2X'
                app.stop1 = 0.5;
                case '4X'
                app.stop1 = 0.25;
            end
            
            switch app.AudiospeedSwitch.Value
                case 'Constant'
                start = app.start1;
                stop = app.start1;
                case 'Variable'
                start = app.start1; 
                stop = app.stop1;  
            end
            
            %define frame time
            t       = app.FramesizemsSlider.Value/1000;
            
            %calculate frame size
            N       = round(t*Fs);
            
            %overlap factor
            olf     =app.OverlapPercentageSlider.Value/100;
            
            %Analysis Hop size
            HA      = round(N-N*olf);
            
            %define number of FFT
            NFFT    =2^(floor(log2(N))+1);
            
            %create hanning window
            win     = 0.5*(1-cos(2*pi*(0:N-1)/N)).';
            
            %calculate number of frame
            L       = length(x);
            
            %zeros padding in the front of x
            x       = [zeros(N,1);x];
            
            %Number of Frame
            NF      = ceil((L+N)/HA);
            
            %Total length of x after all processing
            L2      = (NF-1)*HA+N;
            
            %Zeros padding in the end
            x       = [x;zeros(L2-N-L,1)];
            
            %Q vector
            Q       = linspace(abs(start),abs(stop),NF-1);
            
            %HS vector
            HS      = round(HA.*Q);
            
            %RS vector 
            RS = round(HS.*ps);
            
            %create zeros output vector y[n]
            HS_sum  = sum(RS);
            Ly = HS_sum+N;
            y = zeros(HS_sum+N,1);
            
            %create zeros vector
            phi_m     = zeros(NFFT/2+1,1);
            phi_m1    = zeros(NFFT/2+1,1);
            omega_m1  = zeros(NFFT/2+1,1);
            theta_m   = zeros(NFFT/2+1,1);
            
            %frequency vector
            wk        = (2*pi*(0:NFFT/2)/NFFT).';
            
            %using for loop to create y[n]
            for m=1:NF-1
                %window and FFT
                FFT         = fft(x(m*HA+1:m*HA+N).*win,NFFT);
                %Calculate Magnitude and phase of FFT 
                Xmag        = abs(FFT(1:NFFT/2+1));
                phi_m1      = angle(FFT(1:NFFT/2+1));
                %Calculate theta_m through emoga_m
                omega_m1    = wk+ppa(phi_m1-phi_m-wk*HA)/HA;
                theta_m     = theta_m+RS(m).*omega_m1;
                %reconstruct FFT
                Y           = Xmag.*exp(1i.*theta_m);
                %fix the error from pi by extracting the real part
                Y(1)        = real(Y(1));
                Y(NFFT/2+1) = real(Y(NFFT/2+1));
                % Hermitian-symmetric
                Y_m1        = [Y(1:NFFT/2+1);conj(Y(NFFT/2:-1:2))];
                %ifft
                frame_m1    = ifft(Y_m1,NFFT);
                %extracting the first N sample
                frame_m1    = frame_m1(1:N);
                %accumulation into y
                y(sum(RS(1:m))+1:sum(RS(1:m))+N,1) = frame_m1.*win+y(sum(RS(1:m))+1:sum(RS(1:m))+N,1);
                %Keep phi_m1 as phi_m to next loop
                phi_m = phi_m1;
            end
            %resampling
            y=interp1(y,(1:ps:Ly));
            app.y=y;
            app.Fs=Fs;
            plot(app.UIAxes, x);
            plot(app.UIAxes_2,y);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.y,app.Fs);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite('output.wav',app.y,app.Fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pathTextArea = uitextarea(app.UIFigure);
            app.pathTextArea.Value = {'Cath_short_clip.wav'};
            app.BrowerButton = uibutton(app.UIFigure, 'push');
            app.BrowerButton.ButtonPushedFcn = createCallbackFcn(app, @BrowerButtonPushed, true);
            app.BrowerButton.Text = 'Brower';
            app.StartspeedKnobLabel = uilabel(app.UIFigure);
            app.StartspeedKnobLabel.Text = 'Start speed';
            app.StartspeedKnob = uiknob(app.UIFigure, 'discrete');
            app.StartspeedKnob.Items = {'0.5X', '0.75X', '1X', '1.25X', '2X', '4X'};
            app.StartspeedKnob.Value = '1X';
            app.EndspeedKnobLabel = uilabel(app.UIFigure);
            app.EndspeedKnobLabel.Text = 'End speed';
            app.EndspeedKnob = uiknob(app.UIFigure, 'discrete');
            app.EndspeedKnob.Items = {'0.5X', '0.75X', '1X', '1.25X', '2X', '4X'};
            app.EndspeedKnob.Value = '1X';
            app.AudiospeedSwitchLabel = uilabel(app.UIFigure);
            app.AudiospeedSwitchLabel.Text = 'Audio speed';
            app.AudiospeedSwitch = uiswitch(app.UIFigure, 'slider');
            app.AudiospeedSwitch.Items = {'Constant', 'Variable'};
            app.AudiospeedSwitch.Value = 'Constant';
            app.PleaseputyouraudiofileandAppinthesamefolderLabel_2 = uilabel(app.UIFigure);
            app.PleaseputyouraudiofileandAppinthesamefolderLabel_2.Text = '-End speed knob works only when audio speed is variable';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original audio')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amp')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Time stretched/compressed audio')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Amp')
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Lamp = uilamp(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.OverlapPercentageSliderLabel = uilabel(app.UIFigure);
            app.OverlapPercentageSliderLabel.Text = 'Overlap Percentage(%)';
            app.OverlapPercentageSlider = uislider(app.UIFigure);
            app.OverlapPercentageSlider.Limits = [10 90];
            app.OverlapPercentageSlider.Value = 80;
            app.FramesizemsSliderLabel = uilabel(app.UIFigure);
            app.FramesizemsSliderLabel.Text = 'Frame size(ms)';
            app.FramesizemsSlider = uislider(app.UIFigure);
            app.FramesizemsSlider.Limits = [20 100];
            app.FramesizemsSlider.Value = 50;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PhasevocoderLabel = uilabel(app.UIFigure);
            app.PhasevocoderLabel.Text = 'Phase vocoder';
            app.Pitchshift12EditFieldLabel = uilabel(app.UIFigure);
            app.Pitchshift12EditFieldLabel.Text = 'Pitch shift (+-12)';
            app.Pitchshift12EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MA1_S2119032_Lai_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end